classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ResultadoTextArea       matlab.ui.control.TextArea
        ResultadoTextAreaLabel  matlab.ui.control.Label
        CalcularButton          matlab.ui.control.Button
        Valor2EditField         matlab.ui.control.NumericEditField
        Valor2EditFieldLabel    matlab.ui.control.Label
        Valor1EditField         matlab.ui.control.NumericEditField
        Valor1EditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            % Obtener los datos de entrada ingresados por el usuario
            x_str = app.Valor1EditField.Value;
            y_str = app.Valor2EditField.Value;
            
            % Convertir los datos de entrada en vectores numéricos
            x_cell = strsplit(char(x_str), ',');  % Convertir a vector de celdas
            y_cell = strsplit(char(y_str), ',');
            
            % Convertir las celdas en vectores numéricos
            x = str2double(x_cell);
            y = str2double(y_cell);
            
            % Verificar si la longitud de los vectores es la misma
            if length(x) ~= length(y)
                app.ResultadoTextArea.Value = 'Error: Los vectores deben tener la misma longitud.';
                return;
            end
            
            % Verificar si los datos de entrada son numéricos válidos
            if any(isnan(x)) || any(isnan(y))
                app.ResultadoTextArea.Value = 'Error: Los datos de entrada deben ser numéricos válidos.';
                return;
            end
            
            % Calcular la regresión lineal
            coeffs = polyfit(x, y, 1);
            
            % Mostrar los resultados en el cuadro de texto
            app.ResultadoTextArea.Value = sprintf('Pendiente: %.2f\nTérmino constante: %.2f', coeffs(1), coeffs(2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Valor1EditFieldLabel = uilabel(app.UIFigure);
            app.Valor1EditFieldLabel.Text = 'Valor 1';
            app.Valor1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Valor2EditFieldLabel = uilabel(app.UIFigure);
            app.Valor2EditFieldLabel.Text = 'Valor 2';
            app.Valor2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultadoTextAreaLabel = uilabel(app.UIFigure);
            app.ResultadoTextAreaLabel.Text = 'Resultado: ';
            app.ResultadoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end