classdef inicio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AlanCarrascoJonathanGarcaLabel  matlab.ui.control.Label
        EstaaplicacinderegresinlinealfuerealizadaporLabel  matlab.ui.control.Label
        BienvenidosLabel                matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app = app1; % Crear una instancia de la aplicación y asignarle el nombre "app1"
            abrirSegundaApp(app);
        end
        
        function abrirSegundaApp(app)
            % Cerrar la primera aplicación
            close(app.UIFigure); % Cierra la ventana de la primera aplicación
            
            % Ruta local del archivo .mlapp de la segunda aplicación
            rutaApp2 = '<app1.mlapp>';
            
            % Abrir la segunda aplicación
            open(rutaApp2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'starwars.jpg';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start (:';
            app.BienvenidosLabel = uilabel(app.UIFigure);
            app.BienvenidosLabel.Text = '¡Bienvenidos!';
            app.EstaaplicacinderegresinlinealfuerealizadaporLabel = uilabel(app.UIFigure);
            app.EstaaplicacinderegresinlinealfuerealizadaporLabel.Text = 'Esta aplicación de regresión lineal fue realizada por:';
            app.AlanCarrascoJonathanGarcaLabel = uilabel(app.UIFigure);
            app.AlanCarrascoJonathanGarcaLabel.Text = {'Alan Carrasco'; 'Jonathan García'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = inicio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end